classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        STARTButton               matlab.ui.control.Button
        SLOWLampLabel             matlab.ui.control.Label
        SLOWLamp                  matlab.ui.control.Lamp
        STOPLampLabel             matlab.ui.control.Label
        STOPLamp                  matlab.ui.control.Lamp
        GOODLampLabel             matlab.ui.control.Label
        GOODLamp                  matlab.ui.control.Lamp
        DistancecmEditFieldLabel  matlab.ui.control.Label
        DistancecmEditField       matlab.ui.control.NumericEditField
        HeadlightsEditFieldLabel  matlab.ui.control.Label
        HeadlightsEditField       matlab.ui.control.EditField
        MotorSpeedEditFieldLabel  matlab.ui.control.Label
        MotorSpeedEditField       matlab.ui.control.NumericEditField
        HighTempLampLabel         matlab.ui.control.Label
        HighTempLamp              matlab.ui.control.Lamp
        TempGaugeLabel            matlab.ui.control.Label
        TempGauge                 matlab.ui.control.Gauge
        CoolantGauge              matlab.ui.control.LinearGauge
        CoolantLevelLabel         matlab.ui.control.Label
        LowCoolantLampLabel       matlab.ui.control.Label
        LowCoolantLamp            matlab.ui.control.Lamp
        HL2                       matlab.ui.control.Lamp
        HL1                       matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % Set up communications
            arduino=serialport("COM8",9600,"Timeout",15);
            pause(1)
            
            while 1
                flush(arduino)
                write(arduino,2,'string');
                pause(0.5)
                a=read(arduino,16,'string');
                flush(arduino)
                c_str = regexp(num2str(a), ',', 'split');
              
                
                try
                    distance = str2double(c_str(1));
                    headlights_status = str2double(c_str(2));
                    motor_speed = str2double(c_str(3));
                    temp = str2double(c_str(4));
                    coolant = str2double(c_str(5));
                    
                    % DISTANCE
                    app.DistancecmEditField.Value=distance; % String wrong when < 10 cm
                    
                    % MOTOR SPEED
                    app.MotorSpeedEditField.Value=motor_speed;
                    
                    % TEMP
                    app.TempGauge.Value=temp;
                    if temp >= 75 
                        app.HighTempLamp.Enable = 'on';
                    else
                        app.HighTempLamp.Enable = 'off';
                    end
                    
                    
                    % COOLANT
                    app.CoolantGauge.Value=coolant;
                    if coolant <= 200 
                        app.LowCoolantLamp.Enable = 'on';
                    else
                        app.LowCoolantLamp.Enable = 'off';
                    end
                    
                    
                    % HEADLIGHTS
                    if headlights_status == 0
                        app.HeadlightsEditField.Value="OFF";
                        app.HL1.Enable = 'off';
                        app.HL2.Enable = 'off';
                    end
                    
                    if headlights_status == 1
                        app.HeadlightsEditField.Value="DIM";
                        app.HL1.Enable = 'on';
                        app.HL2.Enable = 'on';
                    end
                    
                    if headlights_status == 2
                        app.HeadlightsEditField.Value="HIGH";
                        app.HL1.Enable = 'on';
                        app.HL2.Enable = 'on';
                    end
                    
                    
                    %LEDS
                    if distance < 12
                        app.GOODLamp.Enable = 'on';
                        app.SLOWLamp.Enable = 'on';
                        app.STOPLamp.Enable = 'on';
                    end
                    
                    if distance > 10 && distance < 16
                        app.GOODLamp.Enable = 'on';
                        app.SLOWLamp.Enable = 'on';
                        app.STOPLamp.Enable = 'off';
                    end
                    
                    if distance > 16 && distance < 24 
                        app.GOODLamp.Enable = 'on';
                        app.SLOWLamp.Enable = 'off';
                        app.STOPLamp.Enable = 'off';
                    end
                    
                    if distance > 24
                        app.GOODLamp.Enable = 'off';
                        app.SLOWLamp.Enable = 'off';
                        app.STOPLamp.Enable = 'off';
                    end    
                    
                catch
                    continue
                end
            
            end
            delete(arduino);
            clear arduino;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SLOWLampLabel = uilabel(app.UIFigure);
            app.SLOWLampLabel.Text = 'SLOW';
            app.SLOWLamp = uilamp(app.UIFigure);
            app.SLOWLamp.Enable = 'off';
            app.STOPLampLabel = uilabel(app.UIFigure);
            app.STOPLampLabel.Text = 'STOP';
            app.STOPLamp = uilamp(app.UIFigure);
            app.STOPLamp.Enable = 'off';
            app.GOODLampLabel = uilabel(app.UIFigure);
            app.GOODLampLabel.Text = 'GOOD';
            app.GOODLamp = uilamp(app.UIFigure);
            app.GOODLamp.Enable = 'off';
            app.DistancecmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
            app.DistancecmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeadlightsEditFieldLabel = uilabel(app.UIFigure);
            app.HeadlightsEditFieldLabel.Text = 'Headlights';
            app.HeadlightsEditField = uieditfield(app.UIFigure, 'text');
            app.MotorSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.MotorSpeedEditFieldLabel.Text = 'Motor Speed (%)';
            app.MotorSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighTempLampLabel = uilabel(app.UIFigure);
            app.HighTempLampLabel.Text = 'High Temp';
            app.HighTempLamp = uilamp(app.UIFigure);
            app.HighTempLamp.Enable = 'off';
            app.TempGaugeLabel = uilabel(app.UIFigure);
            app.TempGaugeLabel.Text = 'Temp';
            app.TempGauge = uigauge(app.UIFigure, 'circular');
            app.CoolantGauge = uigauge(app.UIFigure, 'linear');
            app.CoolantGauge.Limits = [0 500];
            app.CoolantLevelLabel = uilabel(app.UIFigure);
            app.CoolantLevelLabel.Text = 'Coolant Level';
            app.LowCoolantLampLabel = uilabel(app.UIFigure);
            app.LowCoolantLampLabel.Text = 'Low Coolant';
            app.LowCoolantLamp = uilamp(app.UIFigure);
            app.LowCoolantLamp.Enable = 'off';
            app.HL1 = uilamp(app.UIFigure);
            app.HL1.Enable = 'off';
            app.HL2 = uilamp(app.UIFigure);
            app.HL2.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end