classdef lab5_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GreenStatusEditFieldLabel  matlab.ui.control.Label
        GreenStatusEditField       matlab.ui.control.NumericEditField
        WhiteStatusEditFieldLabel  matlab.ui.control.Label
        WhiteStatusEditField       matlab.ui.control.NumericEditField
        RedStatusEditFieldLabel    matlab.ui.control.Label
        RedStatusEditField         matlab.ui.control.NumericEditField
        GreenLEDLampLabel          matlab.ui.control.Label
        GreenLEDLamp               matlab.ui.control.Lamp
        WhiteLEDLampLabel          matlab.ui.control.Label
        WhiteLEDLamp               matlab.ui.control.Lamp
        RedLEDLampLabel            matlab.ui.control.Label
        RedLEDLamp                 matlab.ui.control.Lamp
        CommandEditFieldLabel      matlab.ui.control.Label
        CommandEditField           matlab.ui.control.NumericEditField
        SubmitCommandButton        matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        EndButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        position={'OFF','ON'}; % Description
        arduino;
        data;
        status;
        offcolor={0.94,0.94,0.94};
        bitcode;
        command;
    end
    
    methods (Access = private)
        
        function get_set_status(app)
            app.data=read(app.arduino,1,'uint8');
            app.status=bitget(app.data,3:-1:1);
            
            if app.status(1)==1
                app.GreenLEDLamp.Color='g';
            else
                app.GreenLEDLamp.Color=app.offcolor;
            end
            app.GreenStatusEditField.Value=...
                char(app.position(app.status(1)+1));
            
            if app.status(2)==1
                app.WhiteLEDLamp.Color='yellow';
            else
                app.WhiteLEDLamp.Color=app.offcolor;
            end
            app.WhiteStatusEditField.Value=...
                char(app.position(app.status(2)+1));
            
            if app.status(3)==1
                app.RedLEDLamp.Color='red';
            else
                app.RedLEDLamp.Color=app.offcolor;
            end
            app.RedStatusEditField.Value=...
                char(app.position(app.status(3)+1));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.arduino=serialport("COM8",9600,"Timeout",15);
            pause(1)
            app.get_set_status;
            app.StartButton.Visible=false;
            app.EndButton.Visible=true;
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            delete(app.arduino);
            app.EndButton.Visible=false;
            app.StartButton.Visible=true;
        end

        % Button pushed function: SubmitCommandButton
        function SubmitCommandButtonPushed(app, event)
            app.command=app.CommandEditField.Value;
            inp=app.command;
            app.bitcode=contains(inp,'g')*4+contains(inp,'w')*2+...
                contains(inp,'r');
            write(app.arduino,app.bitcode,'uint8');
            pause(2)
            app.get_set_status;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GreenStatusEditFieldLabel = uilabel(app.UIFigure);
            app.GreenStatusEditFieldLabel.Text = 'Green Status';
            app.GreenStatusEditField = uieditfield(app.UIFigure, 'numeric');
            app.WhiteStatusEditFieldLabel = uilabel(app.UIFigure);
            app.WhiteStatusEditFieldLabel.Text = 'White Status';
            app.WhiteStatusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedStatusEditFieldLabel = uilabel(app.UIFigure);
            app.RedStatusEditFieldLabel.Text = 'Red Status';
            app.RedStatusEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenLEDLampLabel = uilabel(app.UIFigure);
            app.GreenLEDLampLabel.Text = 'Green LED';
            app.GreenLEDLamp = uilamp(app.UIFigure);
            app.WhiteLEDLampLabel = uilabel(app.UIFigure);
            app.WhiteLEDLampLabel.Text = 'White LED';
            app.WhiteLEDLamp = uilamp(app.UIFigure);
            app.RedLEDLampLabel = uilabel(app.UIFigure);
            app.RedLEDLampLabel.Text = 'Red LED';
            app.RedLEDLamp = uilamp(app.UIFigure);
            app.CommandEditFieldLabel = uilabel(app.UIFigure);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubmitCommandButton = uibutton(app.UIFigure, 'push');
            app.SubmitCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitCommandButtonPushed, true);
            app.SubmitCommandButton.Text = 'Submit Command';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end