classdef lab5_gui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        StartButton              matlab.ui.control.Button
        DutyCycleEditFieldLabel  matlab.ui.control.Label
        DutyCycleEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Set up communications
            arduino=serialport("COM8",9600,"Timeout",15);
            pause(1)
            num_points=100;
            DC=zeros(1,num_points);
            V_res=zeros(1,num_points);
            V_pc=zeros(1,num_points);
            I=zeros(1,num_points);
            R=zeros(1,num_points);
            
            for K=0:(num_points)
                DC(K+1)=K;
                flush(arduino)
                write(arduino,2,'string');
                pause(0.5)
                a=read(arduino,4,'string');
                flush(arduino)
                V_res(K+1)=str2double(a)/1023*5;
                V_pc(K+1)=5-V_res(K+1);
                I(K+1)=V_res(K+1)/5000*1000;
                R(K+1)=V_pc(K+1)/I(K+1);
                app.DutyCycleEditField.Value=DC(K+1);
                plot(app.UIAxes,DC(1:(K+1)),V_pc(1:(K+1)),'ko','MarkerFaceColor','yellow')
                plot(app.UIAxes_2,DC(1:(K+1)),I(1:(K+1)),'ko','MarkerFaceColor','yellow')
                plot(app.UIAxes_3,DC(1:(K+1)),R(1:(K+1)),'ko','MarkerFaceColor','yellow')
            end
            delete(arduino);
            clear arduino;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Photocell Voltage')
            xlabel(app.UIAxes, 'Duty Cycle (%)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.UIAxes.XTick = [0 20 40 60 80 100];
            app.UIAxes.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Current')
            xlabel(app.UIAxes_2, 'Duty Cycle (%)')
            ylabel(app.UIAxes_2, 'Current (mA)')
            app.UIAxes_2.XTick = [0 20 40 60 80 100];
            app.UIAxes_2.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Resistance')
            xlabel(app.UIAxes_3, 'Duty Cycle (%)')
            ylabel(app.UIAxes_3, 'Resistance (kΩ)')
            app.UIAxes_3.XTick = [0 20 40 60 80 100];
            app.UIAxes_3.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DutyCycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5_gui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end